classdef TTRSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TTRSimulationUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FinalBoardStateTab              matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FinalScoresTab                  matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        SaveLogfileButton               matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SaveResultsFigureButton         matlab.ui.control.Button
        SaveResultsTextButton           matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        OutputPanel                     matlab.ui.container.Panel
        FormatDropDown                  matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        RulesPanel                      matlab.ui.container.Panel
        RandomSeedDropDown              matlab.ui.control.DropDown
        RandomSeedDropDownLabel         matlab.ui.control.Label
        NumberofIterationsSpinner       matlab.ui.control.Spinner
        NumberofIterationsSpinnerLabel  matlab.ui.control.Label
        RulesetDropDown                 matlab.ui.control.DropDown
        RulesetDropDownLabel            matlab.ui.control.Label
        PlayersPanel                    matlab.ui.container.Panel
        Player4DropDown                 matlab.ui.control.DropDown
        Player4DropDownLabel            matlab.ui.control.Label
        Player3DropDown                 matlab.ui.control.DropDown
        Player3DropDownLabel            matlab.ui.control.Label
        Player2DropDown                 matlab.ui.control.DropDown
        Player2DropDownLabel            matlab.ui.control.Label
        Player1DropDown                 matlab.ui.control.DropDown
        Player1DropDownLabel            matlab.ui.control.Label
        NumberofplayersSpinner          matlab.ui.control.Spinner
        NumberofplayersSpinnerLabel     matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function runButtonPushed(app, event)
            RunSimulation(@initializeBasicGame, app.NumberofplayersSpinner.Value, ...
                                                app.NumberofIterationsSpinner.Value, ...
                                                { app.Player1DropDown.Value, ...
                                                  app.Player2DropDown.Value, ...
                                                  app.Player3DropDown.Value, ...
                                                  app.Player4DropDown.Value}, ...
                                                app.RulesetDropDown.Value, ...
                                                app.RandomSeedDropDown.Value, ...
                                                app.UIAxes, ... % Final Board State
                                                app.UIAxes2); % Final Scores
        end

        % Value changed function: NumberofplayersSpinner
        function NumberofplayersSpinnerValueChanged(app, event)
            value = app.NumberofplayersSpinner.Value;
            if value < 2
                app.Player2DropDown.Enable = false;
                app.Player2DropDownLabel.Enable = false;
            else
                app.Player2DropDown.Enable = true;
                app.Player2DropDownLabel.Enable = true;
            end
            if value < 3
                app.Player3DropDown.Enable = false;
                app.Player3DropDownLabel.Enable = false;
            else
                app.Player3DropDown.Enable = true;
                app.Player3DropDownLabel.Enable = true;
            end
            if value < 4
                app.Player4DropDown.Enable = false;
                app.Player4DropDownLabel.Enable = false;
            else
                app.Player4DropDown.Enable = true;
                app.Player4DropDownLabel.Enable = true;
            end
        end

        % Button pushed function: ResetButton
        function resetButtonPushed(app, event)
            app.NumberofplayersSpinner.Value = 2;
            app.Player1DropDown.Value = "Dummy Player";
            app.Player2DropDown.Value = "Dummy Player";
            app.Player3DropDown.Value = "Dummy Player";
            app.Player4DropDown.Value = "Dummy Player";
            app.NumberofplayersSpinnerValueChanged(event);

            app.RulesetDropDown.Value = "Default Rules";
            app.NumberofIterationsSpinner.Value = 1;
            app.RandomSeedDropDown.Value = "None";

            app.FormatDropDown.Value = "Final Scores";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TTRSimulationUIFigure = uifigure('Visible', 'off');
            app.TTRSimulationUIFigure.Name = 'TTR Simulation';
            app.TTRSimulationUIFigure.Icon = 'icon.jpg';
            app.RunButton = uibutton(app.TTRSimulationUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PlayersPanel = uipanel(app.TTRSimulationUIFigure);
            app.PlayersPanel.Title = 'Players';
            app.NumberofplayersSpinnerLabel = uilabel(app.PlayersPanel);
            app.NumberofplayersSpinnerLabel.Text = 'Number of players';
            app.NumberofplayersSpinner = uispinner(app.PlayersPanel);
            app.NumberofplayersSpinner.Limits = [2 4];
            app.NumberofplayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofplayersSpinnerValueChanged, true);
            app.NumberofplayersSpinner.Value = 2;
            app.Player1DropDownLabel = uilabel(app.PlayersPanel);
            app.Player1DropDownLabel.Text = 'Player 1';
            app.Player1DropDown = uidropdown(app.PlayersPanel);
            app.Player1DropDown.Items = {'Dummy Player', 'Long Route Player', 'Destination Ticket Player'};
            app.Player1DropDown.Value = 'Dummy Player';
            app.Player2DropDownLabel = uilabel(app.PlayersPanel);
            app.Player2DropDownLabel.Text = 'Player 2';
            app.Player2DropDown = uidropdown(app.PlayersPanel);
            app.Player2DropDown.Items = {'Dummy Player', 'Long Route Player', 'Destination Ticket Player'};
            app.Player2DropDown.Value = 'Dummy Player';
            app.Player3DropDownLabel = uilabel(app.PlayersPanel);
            app.Player3DropDownLabel.Enable = 'off';
            app.Player3DropDownLabel.Text = 'Player 3';
            app.Player3DropDown = uidropdown(app.PlayersPanel);
            app.Player3DropDown.Items = {'Dummy Player', 'Long Route Player', 'Destination Ticket Player'};
            app.Player3DropDown.Enable = 'off';
            app.Player3DropDown.Value = 'Dummy Player';
            app.Player4DropDownLabel = uilabel(app.PlayersPanel);
            app.Player4DropDownLabel.Enable = 'off';
            app.Player4DropDownLabel.Text = 'Player 4';
            app.Player4DropDown = uidropdown(app.PlayersPanel);
            app.Player4DropDown.Items = {'Dummy Player', 'Long Route Player', 'Destination Ticket Player'};
            app.Player4DropDown.Enable = 'off';
            app.Player4DropDown.Value = 'Dummy Player';
            app.RulesPanel = uipanel(app.TTRSimulationUIFigure);
            app.RulesPanel.Title = 'Rules';
            app.RulesetDropDownLabel = uilabel(app.RulesPanel);
            app.RulesetDropDownLabel.Text = 'Ruleset';
            app.RulesetDropDown = uidropdown(app.RulesPanel);
            app.RulesetDropDown.Items = {'Default Rules'};
            app.RulesetDropDown.Value = 'Default Rules';
            app.NumberofIterationsSpinnerLabel = uilabel(app.RulesPanel);
            app.NumberofIterationsSpinnerLabel.Text = 'Number of Iterations';
            app.NumberofIterationsSpinner = uispinner(app.RulesPanel);
            app.NumberofIterationsSpinner.Limits = [1 Inf];
            app.NumberofIterationsSpinner.Value = 1;
            app.RandomSeedDropDownLabel = uilabel(app.RulesPanel);
            app.RandomSeedDropDownLabel.Text = 'Random Seed';
            app.RandomSeedDropDown = uidropdown(app.RulesPanel);
            app.RandomSeedDropDown.Items = {'Consistent', 'Shuffle'};
            app.RandomSeedDropDown.Value = 'Consistent';
            app.OutputPanel = uipanel(app.TTRSimulationUIFigure);
            app.OutputPanel.Title = 'Output';
            app.FormatDropDownLabel = uilabel(app.OutputPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.OutputPanel);
            app.FormatDropDown.Items = {'Final Scores'};
            app.FormatDropDown.Value = 'Final Scores';
            app.TextArea = uitextarea(app.TTRSimulationUIFigure);
            app.TextArea.Editable = 'off';
            app.SaveResultsTextButton = uibutton(app.TTRSimulationUIFigure, 'push');
            app.SaveResultsTextButton.Enable = 'off';
            app.SaveResultsTextButton.Text = 'Save Results Text';
            app.SaveResultsFigureButton = uibutton(app.TTRSimulationUIFigure, 'push');
            app.SaveResultsFigureButton.Enable = 'off';
            app.SaveResultsFigureButton.Text = 'Save Results Figure';
            app.ResetButton = uibutton(app.TTRSimulationUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveLogfileButton = uibutton(app.TTRSimulationUIFigure, 'push');
            app.SaveLogfileButton.Enable = 'off';
            app.SaveLogfileButton.Text = 'Save Logfile';
            app.TabGroup = uitabgroup(app.TTRSimulationUIFigure);
            app.FinalBoardStateTab = uitab(app.TabGroup);
            app.FinalBoardStateTab.Title = 'Final Board State';
            app.UIAxes = uiaxes(app.FinalBoardStateTab);
            title(app.UIAxes, 'Final Board State')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FinalScoresTab = uitab(app.TabGroup);
            app.FinalScoresTab.Title = 'Final Scores';
            app.UIAxes2 = uiaxes(app.FinalScoresTab);
            title(app.UIAxes2, 'Final Scores')
            xlabel(app.UIAxes2, 'Players')
            ylabel(app.UIAxes2, 'Points')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.TTRSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTRSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TTRSimulationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TTRSimulationUIFigure)
        end
    end
end